classdef LoginRegister < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Password        matlab.ui.control.TextArea
        PasswordLabel   matlab.ui.control.Label
        Username        matlab.ui.control.TextArea
        UserLabel       matlab.ui.control.Label
        REGISTERButton  matlab.ui.control.Button
        LOGINLabel      matlab.ui.control.Label
        SIGNINButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        user char
        pass char
        steg
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: REGISTERButton
        function REGISTERButtonPushed(app, event)
            if isempty(app.user)&&isempty(app.pass)
                errordlg('No Input');
                return
            elseif isempty(app.user)
                errordlg('No Username Input');
                return
            elseif isempty(app.pass)
                errordlg('No Password Input');
                return
            else
                registerdlg(app.user, app.pass);
                return 
            end
        end

        % Value changed function: Username
        function UsernameValueChanged(app, event)
            app.user = char(app.Username.Value);
        end

        % Value changed function: Password
        function PasswordValueChanged(app, event)
            app.pass = char(app.Password.Value);
        end

        % Button pushed function: SIGNINButton
        function SIGNINButtonPushed(app, event)
            if isempty(app.user)&&isempty(app.pass)
                errordlg('No Input');
                return
            elseif isempty(app.user)
                errordlg('No Username Input');
                return
            elseif isempty(app.pass)
                errordlg('No Password Input');
                return
            else
                success = logindlg(app.user, app.pass);
                if success == 1
                    app.steg = steg(app, app.user)
                    app.delete;
                end
                return 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SIGNINButton = uibutton(app.UIFigure, 'push');
            app.SIGNINButton.ButtonPushedFcn = createCallbackFcn(app, @SIGNINButtonPushed, true);
            app.SIGNINButton.Text = 'SIGN IN';
            app.LOGINLabel = uilabel(app.UIFigure);
            app.LOGINLabel.Text = 'LOGIN';
            app.REGISTERButton = uibutton(app.UIFigure, 'push');
            app.REGISTERButton.ButtonPushedFcn = createCallbackFcn(app, @REGISTERButtonPushed, true);
            app.REGISTERButton.Text = 'REGISTER';
            app.UserLabel = uilabel(app.UIFigure);
            app.UserLabel.Text = 'Username';
            app.Username = uitextarea(app.UIFigure);
            app.Username.ValueChangedFcn = createCallbackFcn(app, @UsernameValueChanged, true);
            app.PasswordLabel = uilabel(app.UIFigure);
            app.PasswordLabel.Text = 'Password';
            app.Password = uitextarea(app.UIFigure);
            app.Password.ValueChangedFcn = createCallbackFcn(app, @PasswordValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginRegister

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end